classdef MainGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FigureWindow                matlab.ui.Figure
        SettingsMenu                matlab.ui.container.Menu
        NumberofvotesperplayerMenu  matlab.ui.container.Menu
        nVotes1Menu                 matlab.ui.container.Menu
        nVotes2Menu                 matlab.ui.container.Menu
        nVotes3Menu                 matlab.ui.container.Menu
        nVotes4Menu                 matlab.ui.container.Menu
        nVotes5Menu                 matlab.ui.container.Menu
        ModifyrestaurantlistMenu    matlab.ui.container.Menu
        LoadrestaurantlistMenu      matlab.ui.container.Menu
        VersionMenu                 matlab.ui.container.Menu
        GotoGitHubrepoMenu          matlab.ui.container.Menu
        SubmitabugreportfeaturerequestetcMenu  matlab.ui.container.Menu
        WindowGrid                  matlab.ui.container.GridLayout
        MainGrid                    matlab.ui.container.GridLayout
        GridLayout3                 matlab.ui.container.GridLayout
        TextArea                    matlab.ui.control.TextArea
        RestaurantsGrid             matlab.ui.container.GridLayout
        GridLayout5                 matlab.ui.container.GridLayout
        VotesRemainingLabel         matlab.ui.control.Label
        VotesRemainingValue         matlab.ui.control.Label
        VotingButtonsGrid           matlab.ui.container.GridLayout
        ResetAllVotesButton         matlab.ui.control.Button
        SurpriseMeButton            matlab.ui.control.Button
        ClearMyVotesButton          matlab.ui.control.Button
        VoteButton                  matlab.ui.control.Button
        GridLayout                  matlab.ui.container.GridLayout
        GridLayout2                 matlab.ui.container.GridLayout
        PointerPositionSlider       matlab.ui.control.Slider
        PointerPositionSliderLabel  matlab.ui.control.Label
        SpinButton                  matlab.ui.control.Button
        WheelAxes                   matlab.ui.control.UIAxes
    end

    %% Properties -- data
    properties (Access = private)
        % Restaurant objects loaded in the app.
        restaurants (1,:) WheelOfFortuneCookies.Restaurant.Restaurant

        % Handle of "the wheel".
        wheel WheelOfFortuneCookies.SpinnerWheel {mustBeScalarOrEmpty}

        % Handles of the controls for the components used to vote for restaurants.
        restaurantVoteControls (1,:) WheelOfFortuneCookies.RestaurantVoteComponent

        % Number of votes that each player gets. This counts both the "add" and "remove" votes.
        nVotesPerPlayer (1,1) double...
            {mustBePositive,mustBeInteger,mustBeLessThanOrEqual(nVotesPerPlayer,5)} = 2
    end

    %% Properties -- voting
    properties (Dependent, Access = private)
        % Number of restaurants that have been added to the app.
        nRestaurants (1,1) double

        % Total number of votes that have been cast.
        nVotesTotal (1,1) double

        % Total number of votes that are waiting to be cast. This counts both the "add" and "remove"
        % votes.
        nVotesTempTotal (1,1) double
    end

    %% Properties -- versioning
    properties (Constant, Access = private)
        % Version of the app. Right now this is only used as a visual indicator -- nothing actually
        % reads or processes this number.
        versionNumber = "2022.12"

        % URL of the GitHub repo where the most recent version of the code (any any development
        % versions, issues, etc.) can be found.
        githubRepoLink = "https://github.com/ThatMechEGuy/WheelOfFortuneCookies"

        % Direct link to creating a new "issue" for the project (bug reports, feature requests,
        % etc.).
        githubIssuesLink = "https://github.com/ThatMechEGuy/WheelOfFortuneCookies/issues/new"
    end

    %% Properties -- child GUIs
    properties (Access = private)
        % Handle of the restaurant management GUI.
        restaurantGUI WheelOfFortuneCookies.Restaurant.ManagmentGUI

        % Handle of the "winner screen" GUI.
        winnerGUI WheelOfFortuneCookies.WinnerMessageGUI

        % Listener to detect when the restraurant management GUI is closed.
        elRestaurantGuiClosed event.listener

        % Listener to detect when the restraurant management GUI has changes that should be applied
        % before closing.
        elRestaurantGuiClosedWithChanges event.listener
    end

    %% Properties -- general
    properties (Access = private)
        % Progress bar.
        progressBar matlab.ui.dialog.ProgressDialog
    end

    properties (Access = private, Constant)
        % Path to the persistent data that maintains the last-used number of votes and restaurant
        % list.
        stateDataFileName (1,1) string = fullfile(getenv("ProgramData"),"WheelOfFortuneCookies","State Data.mat")
    end

    %% Methods -- set/get
    methods
        function out = get.nRestaurants(app)
            out = numel(app.restaurants);
        end

        function out = get.nVotesTotal(app)
            out = sum([app.restaurants.nVotes]);
        end

        function out = get.nVotesTempTotal(app)
            out = sum(abs([app.restaurants.nVotesTemp]));
        end
    end
    
    %% Methods -- restaurant controls
    methods (Access = private)
        function addRestaurantControls(app)
            delete(app.RestaurantsGrid.Children);
            app.restaurantVoteControls = WheelOfFortuneCookies.RestaurantVoteComponent.empty;

            nColumns = 4;
            nRows = ceil(app.nRestaurants/nColumns);
             
            app.RestaurantsGrid.RowHeight = repmat({'fit'},[1,nRows]);
            app.RestaurantsGrid.ColumnWidth = repmat({'1x'},[1,nColumns]);

            for restaurant = app.restaurants
                newControl = WheelOfFortuneCookies.RestaurantVoteComponent(app.RestaurantsGrid,...
                    restaurant=restaurant,voteChangedFcn=@(~,~)app.restaurantVoteChangedCallback);
                app.restaurantVoteControls(end+1) = newControl;
            end
        end

        function restaurantVoteChangedCallback(app)
            arguments (Input)
                app (1,1) WheelOfFortuneCookies.MainGUI
            end

            app.updateVoteButtonsEnabledState;
            app.VotesRemainingValue.Text = string(app.nVotesPerPlayer - app.nVotesTempTotal);
        end

        function addRemoveVoteButtonClickedCallback(app,R,pm)

            R.nVotesTemp = R.nVotesTemp + pm;

            switch sign(R.nVotesTemp)
                case 1
                    textColor = [0,0.5,0];
                    signChar = '+';
                    backgroundColor = [0.8,1,0.8];
                case 0
                    textColor = 'k';
                    signChar = '';
                    backgroundColor = 'w';
                case -1
                    textColor = 'r';
                    signChar = '';
                    backgroundColor = [1,0.8,0.8];
            end

            R.UserData.EditField.Value = sprintf('%s%i',signChar,R.nVotesTemp);
            R.UserData.EditField.FontColor = textColor;
            R.UserData.EditField.BackgroundColor = backgroundColor;


            app.updateVoteButtonsEnabledState;
            app.VotesRemainingValue.Text = num2str(app.nVotesPerPlayer - app.nVotesTempTotal);
        end

        function restaurantGuiClosed(app)
            delete(app.elRestaurantGuiClosed);
            delete(app.progressBar);

            delete(app.elRestaurantGuiClosed);
            delete(app.elRestaurantGuiClosedWithChanges);
        end

        function restaurantGuiClosedWithChanges(app)
            app.restaurantGuiClosed;

            app.restaurants = WheelOfFortuneCookies.Restaurant.Restaurant.empty;
            for i = 1:numel(app.restaurantGUI.restaurants)
                app.restaurants(i) = app.restaurantGUI.restaurants(i).duplicate;
            end

            app.setupRestaurantControls;
        end

        function setupRestaurantControls(app)
            app.addRestaurantControls;
            
            app.restaurantVoteChangedCallback;

            app.wheel = WheelOfFortuneCookies.SpinnerWheel(app.WheelAxes);
            app.drawWheel;
        end
    end

    %% Methods -- vote counting
    methods (Access = private)
        function updateVoteButtonsEnabledState(app)
            additionalVotesAreAllowed = app.nVotesTempTotal < app.nVotesPerPlayer;

            app.VoteButton.Enable = app.nVotesTempTotal == app.nVotesPerPlayer;
            app.SurpriseMeButton.Enable = additionalVotesAreAllowed;
            app.ClearMyVotesButton.Enable = app.nVotesTempTotal > 0;
            app.ResetAllVotesButton.Enable = app.nVotesTotal > 0;

            app.PointerPositionSlider.Enable = app.nVotesTotal > 0;
            app.SpinButton.Enable = app.nVotesTotal > 0;

            for restaurantVoteControl = app.restaurantVoteControls
                nVotes = restaurantVoteControl.restaurant.nVotes;
                nVotesTemp = restaurantVoteControl.restaurant.nVotesTemp;

                if additionalVotesAreAllowed
                    restaurantVoteControl.addButtonEnabled = true;
                    restaurantVoteControl.subtractButtonEnabled = ...
                        nVotesTemp > 0 || abs(nVotesTemp) < nVotes;
                    restaurantVoteControl.labelAndEditFieldEnabled = true;
                else
                    restaurantVoteControl.addButtonEnabled = nVotesTemp < 0;
                    restaurantVoteControl.subtractButtonEnabled = nVotesTemp > 0;
                    restaurantVoteControl.labelAndEditFieldEnabled = nVotesTemp ~= 0;
                end
            end
        end

        function resetAllVotes(app)
            hasAnyVotes = abs([app.restaurants.nVotesTemp]) > 0 | [app.restaurants.nVotes] > 0;

            for restaurant = app.restaurants(hasAnyVotes)
                restaurant.resetVotes;
            end

            app.restaurantVoteChangedCallback;
            app.drawWheel;
        end
    end

    %% Methods -- the wheel
    methods (Access = private)
        function drawWheel(app)
            arguments
                app (1,1) WheelOfFortuneCookies.MainGUI
            end

            app.wheel.draw(app.restaurants);
        end
    end

    methods (Static, Access = private)
        function updateVoteCounts(e,EF)
            R = e.AffectedObject;

            EF.Value = num2str(R.nVotes);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.VersionMenu.Text = "Version " + app.versionNumber;


            % savePath = fileparts(app.stateDataFileName);
            if exist(app.stateDataFileName,"file")
               S = load(app.stateDataFileName);

               app.nVotesPerPlayer = S.nVotesPerPlayer;
               app.restaurants = S.restaurants;
            else
                defaultRestaurantsFile = fullfile(...
                WheelOfFortuneCookies.HelperFunctions.installationLocation,...
                "+WheelOfFortuneCookies","+Restaurant","Defaults","UMN Restaurants.mat");
                app.restaurants = WheelOfFortuneCookies.Restaurant.HelperFunctions.loadFromFile(...
                    defaultRestaurantsFile);
            end

            try
                app.("nVotes"+app.nVotesPerPlayer+"Menu").Checked = true;
            catch
                app.nVotes2Menu.Checked = true;
                app.nVotesPerPlayer = 2;
            end

            app.setupRestaurantControls;

            movegui(app.FigureWindow,"center");
        end

        % Button pushed function: SpinButton
        function SpinButtonPushed(app, event)
            delete(app.winnerGUI)

            if app.VoteButton.Enable == true
                app.VoteButtonPushed;
            end
            

            set(app.restaurantVoteControls,enable=false);
            app.ResetAllVotesButton.Enable = false;
            app.ClearMyVotesButton.Enable = false;
            app.SurpriseMeButton.Enable = false;
            app.VoteButton.Enable = false;
            app.VotesRemainingLabel.Enable = false;
            app.VotesRemainingValue.Enable = false;
            set(app.SettingsMenu.Children,Enable=false);
            
            app.SpinButton.Enable = false;
            app.PointerPositionSlider.Enable = false;

            winnerRestaurant = app.wheel.spin;

            if ~isempty(winnerRestaurant)
                app.winnerGUI = WheelOfFortuneCookies.WinnerMessageGUI(winnerRestaurant);
                posChild = app.winnerGUI.FigureWindow.Position;
                posSelf = app.FigureWindow.Position;

                widthChild = posChild(3);
                heightChild = posChild(4);
                xSelf = posSelf(1);
                ySelf = posSelf(2);
                widthSelf = posSelf(3);
                heightSelf = posSelf(4);

                app.winnerGUI.FigureWindow.Position(1:2) = [xSelf+(widthSelf-widthChild)/2,ySelf+(heightSelf-heightChild)/2];
            else
                uialert(app.FigureWindow,"Wow, you sure are lucky! You somehow managed to " +...
                    "land exactly between two restaurants." + newline + newline + ...
                    "Send a picture of this message to Nate to impress him, then spin again " + ...
                    "to find a winner.","Tie",Icon="error");
            end

            app.PointerPositionSlider.Enable = true;
            app.SpinButton.Enable = true;

            set(app.restaurantVoteControls,enable=true);
            app.ResetAllVotesButton.Enable = true;
            app.SurpriseMeButton.Enable = true;
            app.VotesRemainingLabel.Enable = true;
            app.VotesRemainingValue.Enable = true;
            set(app.SettingsMenu.Children,Enable=true);
        end

        % Button pushed function: VoteButton
        function VoteButtonPushed(app, event)
            hasTempVotes = abs([app.restaurants.nVotesTemp])>0;
            
            for restaurant = app.restaurants(hasTempVotes)
                restaurant.countTempVotes;
            end
            app.drawWheel;
        end

        % Button pushed function: ResetAllVotesButton
        function ResetAllVotesButtonPushed(app, event)
            % Prompt the user.
            dlgAns = uiconfirm(app.FigureWindow,...
                'Are you sure you''d like to reset all of the votes?',...
                'Confirm Reset','Options',{'Yes','No'},'DefaultOption','No');
            if strcmp(dlgAns,'No')
                return
            end
            
            app.resetAllVotes;
        end

        % Button pushed function: ClearMyVotesButton
        function ClearMyVotesButtonPushed(app, event)
            hasTempVotes = abs([app.restaurants.nVotesTemp])>0;

            for restaurant = app.restaurants(hasTempVotes)
                restaurant.resetVotes(resetTempVotesOnly=true);
                app.restaurantVoteChangedCallback;
            end
        end

        % Button pushed function: SurpriseMeButton
        function SurpriseMeButtonPushed(app, event)
            alreadySelectedRestaurantIndices = find(abs([app.restaurants.nVotesTemp])>0);
            
            while app.nVotesTempTotal < app.nVotesPerPlayer
                restaurantIndex = randi(app.nRestaurants);

                if any(restaurantIndex == alreadySelectedRestaurantIndices)
                    continue
                end
                
                restaurant = app.restaurants(restaurantIndex);
                voteShouldBeAdded = rand > 0.5;
                voteCanBeSubracted = -restaurant.nVotesTemp < restaurant.nVotes;
                if ~voteShouldBeAdded && voteCanBeSubracted
                    restaurant.nVotesTemp = restaurant.nVotesTemp - 1;
                else
                    restaurant.nVotesTemp = restaurant.nVotesTemp + 1;
                end
            end

        end

        % Value changing function: PointerPositionSlider
        function PointerPositionSliderValueChanging(app, event)
            app.wheel.drawPointer(event.Value);
        end

        % Menu selected function: nVotes1Menu, nVotes2Menu, nVotes3Menu, 
        % ...and 2 other components
        function nVotesMenuSelected(app, event)
            % Get the handle of the menu option that was just selected.
            mnu = event.Source;

            if mnu.Checked
                return
            end

            if app.ResetAllVotesButton.Enable == true || app.nVotesTempTotal > 0
                dlgAns = uiconfirm(app.FigureWindow,...
                    "Changing the number of votes per player will reset all current votes. Continue?",...
                    "Confirm Changing Number of Votes",Options={'Yes','No'},DefaultOption='No');
                if strcmp(dlgAns,'No')
                    return
                end
            end

            app.resetAllVotes;


            mnu.Checked = true;
            

            % Go through all other items in the current list and uncheck them.
            for C = mnu.Parent.Children.'
                if C ~= mnu
                    C.Checked = false;
                end
            end

            app.nVotesPerPlayer = str2double(mnu.Text);
            app.VotesRemainingValue.Text = num2str(app.nVotesPerPlayer);
        end

        % Menu selected function: GotoGitHubrepoMenu
        function GotoGitHubrepoMenuSelected(app, event)
            web(app.githubRepoLink);
        end

        % Menu selected function: SubmitabugreportfeaturerequestetcMenu
        function SubmitabugreportfeaturerequestetcMenuSelected(app, event)
            web(app.githubIssuesLink);
        end

        % Menu selected function: ModifyrestaurantlistMenu
        function ModifyrestaurantlistMenuSelected(app, event)
            app.progressBar = uiprogressdlg(app.FigureWindow,Indeterminate=true,...
                title="Modifying Restaurant List",message="The restaurant list modification " +...
                "GUI is open. Close it to continue using this window.");

            app.restaurantGUI = WheelOfFortuneCookies.Restaurant.ManagmentGUI(app,app.restaurants);

            app.elRestaurantGuiClosed = listener(app.restaurantGUI,"ObjectBeingDestroyed",...
                @(~,~)app.restaurantGuiClosed);
            app.elRestaurantGuiClosedWithChanges = listener(app.restaurantGUI,"newDataAvailable",...
                @(~,~)app.restaurantGuiClosedWithChanges);
        end

        % Close request function: FigureWindow
        function FigureWindowCloseRequest(app, event)
            app.resetAllVotes;

            S.nVotesPerPlayer = app.nVotesPerPlayer;
            S.restaurants = app.restaurants;

            savePath = fileparts(app.stateDataFileName);
            if ~exist(savePath,"dir")
               mkdir(savePath);
            end
            save(app.stateDataFileName,"-struct","S");

            try
                delete(app.restaurantGUI)
                delete(app.winnerGUI)
                delete(app)
            catch
                delete(app)
            end
        end

        % Menu selected function: LoadrestaurantlistMenu
        function LoadrestaurantlistMenuSelected(app, event)
            
            if app.nVotesTotal ~= 0 && "Cancel" == uiconfirm(app.FigureWindow,...
                    "All votes will be reset when loading the new restaurant list.",...
                    "Load Restaurants",DefaultOption="Cancel",Options=["OK","Cancel"])
                return
            end
            
            app.progressBar = uiprogressdlg(app.FigureWindow,Title="Load",...
                Message="Select a file to load...",Indeterminate=true);
            
            % Get the file using the built in file selecting GUI.
            [filename,pathname] = uigetfile(["*.mat","MAT-files"],"Select Restaurants File");
            % Prevent the GUIs from being hidden behind the MATLAB window.
            figure(app.FigureWindow);
            
            % If a file was selected, process the input.
            if ischar(filename) && ischar(pathname)
                app.resetAllVotes;

                restaurantsFilename = string(fullfile(pathname,filename));
                app.restaurants = WheelOfFortuneCookies.Restaurant.HelperFunctions.loadFromFile(...
                    restaurantsFilename);

                app.setupRestaurantControls;
            end

            app.progressBar.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.FigureWindow = uifigure('Visible', 'off');
            app.FigureWindow.Name = 'Wheel of Fortune Cookies';
            app.FigureWindow.Icon = fullfile(pathToMLAPP, 'Images', 'Logo.png');
            app.FigureWindow.CloseRequestFcn = createCallbackFcn(app, @FigureWindowCloseRequest, true);
            app.SettingsMenu = uimenu(app.FigureWindow);
            app.SettingsMenu.Text = 'Settings';
            app.NumberofvotesperplayerMenu = uimenu(app.SettingsMenu);
            app.NumberofvotesperplayerMenu.Text = 'Number of votes per player';
            app.nVotes1Menu = uimenu(app.NumberofvotesperplayerMenu);
            app.nVotes1Menu.MenuSelectedFcn = createCallbackFcn(app, @nVotesMenuSelected, true);
            app.nVotes1Menu.Text = '1';
            app.nVotes2Menu = uimenu(app.NumberofvotesperplayerMenu);
            app.nVotes2Menu.MenuSelectedFcn = createCallbackFcn(app, @nVotesMenuSelected, true);
            app.nVotes2Menu.Text = '2';
            app.nVotes3Menu = uimenu(app.NumberofvotesperplayerMenu);
            app.nVotes3Menu.MenuSelectedFcn = createCallbackFcn(app, @nVotesMenuSelected, true);
            app.nVotes3Menu.Text = '3';
            app.nVotes4Menu = uimenu(app.NumberofvotesperplayerMenu);
            app.nVotes4Menu.MenuSelectedFcn = createCallbackFcn(app, @nVotesMenuSelected, true);
            app.nVotes4Menu.Text = '4';
            app.nVotes5Menu = uimenu(app.NumberofvotesperplayerMenu);
            app.nVotes5Menu.MenuSelectedFcn = createCallbackFcn(app, @nVotesMenuSelected, true);
            app.nVotes5Menu.Text = '5';
            app.ModifyrestaurantlistMenu = uimenu(app.SettingsMenu);
            app.ModifyrestaurantlistMenu.MenuSelectedFcn = createCallbackFcn(app, @ModifyrestaurantlistMenuSelected, true);
            app.ModifyrestaurantlistMenu.Text = 'Modify restaurant list...';
            app.LoadrestaurantlistMenu = uimenu(app.SettingsMenu);
            app.LoadrestaurantlistMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadrestaurantlistMenuSelected, true);
            app.LoadrestaurantlistMenu.Text = 'Load restaurant list...';
            app.VersionMenu = uimenu(app.FigureWindow);
            app.VersionMenu.Text = 'Version ####.##';
            app.GotoGitHubrepoMenu = uimenu(app.VersionMenu);
            app.GotoGitHubrepoMenu.MenuSelectedFcn = createCallbackFcn(app, @GotoGitHubrepoMenuSelected, true);
            app.GotoGitHubrepoMenu.Text = 'Go to GitHub repo...';
            app.SubmitabugreportfeaturerequestetcMenu = uimenu(app.VersionMenu);
            app.SubmitabugreportfeaturerequestetcMenu.MenuSelectedFcn = createCallbackFcn(app, @SubmitabugreportfeaturerequestetcMenuSelected, true);
            app.SubmitabugreportfeaturerequestetcMenu.Text = 'Submit a bug report/feature request/etc. ...';
            app.WindowGrid = uigridlayout(app.FigureWindow);
            app.MainGrid = uigridlayout(app.WindowGrid);
            app.GridLayout = uigridlayout(app.MainGrid);
            app.WheelAxes = uiaxes(app.GridLayout);
            title(app.WheelAxes, 'Title')
            xlabel(app.WheelAxes, 'X')
            ylabel(app.WheelAxes, 'Y')
            zlabel(app.WheelAxes, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.SpinButton = uibutton(app.GridLayout2, 'push');
            app.SpinButton.ButtonPushedFcn = createCallbackFcn(app, @SpinButtonPushed, true);
            app.SpinButton.Text = 'Spin';
            app.PointerPositionSliderLabel = uilabel(app.GridLayout2);
            app.PointerPositionSliderLabel.Text = 'Pointer Position';
            app.PointerPositionSlider = uislider(app.GridLayout2);
            app.PointerPositionSlider.Limits = [0 360];
            app.PointerPositionSlider.MajorTicks = [];
            app.PointerPositionSlider.MajorTickLabels = {''};
            app.PointerPositionSlider.ValueChangingFcn = createCallbackFcn(app, @PointerPositionSliderValueChanging, true);
            app.PointerPositionSlider.MinorTicks = [];
            app.PointerPositionSlider.Value = 180;
            app.GridLayout3 = uigridlayout(app.MainGrid);
            app.VotingButtonsGrid = uigridlayout(app.GridLayout3);
            app.VoteButton = uibutton(app.VotingButtonsGrid, 'push');
            app.VoteButton.ButtonPushedFcn = createCallbackFcn(app, @VoteButtonPushed, true);
            app.VoteButton.Text = 'Vote';
            app.ClearMyVotesButton = uibutton(app.VotingButtonsGrid, 'push');
            app.ClearMyVotesButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMyVotesButtonPushed, true);
            app.ClearMyVotesButton.Text = 'Clear My Votes';
            app.SurpriseMeButton = uibutton(app.VotingButtonsGrid, 'push');
            app.SurpriseMeButton.ButtonPushedFcn = createCallbackFcn(app, @SurpriseMeButtonPushed, true);
            app.SurpriseMeButton.Text = 'Surprise Me';
            app.ResetAllVotesButton = uibutton(app.VotingButtonsGrid, 'push');
            app.ResetAllVotesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAllVotesButtonPushed, true);
            app.ResetAllVotesButton.Text = 'Reset All Votes';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.VotesRemainingValue = uilabel(app.GridLayout5);
            app.VotesRemainingValue.Text = '5';
            app.VotesRemainingLabel = uilabel(app.GridLayout5);
            app.VotesRemainingLabel.Text = 'Votes Remaining:';
            app.RestaurantsGrid = uigridlayout(app.GridLayout3);
            app.TextArea = uitextarea(app.GridLayout3);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Welcome to the Wheel of Fortune Cookies! Cast your votes below (either for or against a restaurant), then spin the wheel when everyone has voted. Don''t forget to change the pointer location if you want to make sure no one thinks you''re cheating!'; ''; 'You can change the list of restaurants and the number of votes each person gets through the "Settings" menu.'};
            % Show the figure after all components are created
            app.FigureWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainGUI

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.FigureWindow)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.FigureWindow)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FigureWindow)
        end
    end
end