classdef RestaurantVoteComponent < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        MainGrid             matlab.ui.container.GridLayout
        RestaurantNameLabel  matlab.ui.control.Label
        ControlsGrid         matlab.ui.container.GridLayout
        ValueEditField       matlab.ui.control.EditField
        AddButton            matlab.ui.control.Button
        SubtractButton       matlab.ui.control.Button
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        voteChanged % Runs whenever a vote is added or removed.
    end

    properties (AbortSet, Access = public, GetObservable, SetObservable)
        addButtonEnabled (1,1) logical = true; % When true, the "Add" button is enabled.
        enable (1,1) logical = true; % When false, all controls are disabled. When true, controls that should be enabled are enabled.
        labelAndEditFieldEnabled (1,1) logical = true; % When true, the restaurant name label and the votes edit field are enabled.
        subtractButtonEnabled (1,1) logical = true; % When true, the "subtract" button is enabled.
    end
    
    properties (Access = private)
        elTempVotesChanged event.listener {mustBeScalarOrEmpty(elTempVotesChanged)} = event.listener.empty; % Detects when the number of temporary votes has changed.
        elVotesChanged event.listener {mustBeScalarOrEmpty(elVotesChanged)} = event.listener.empty; % Detects when the number of votes has changed.
    end
    
    properties (Access = public)
        restaurant WheelOfFortuneCookies.Restaurant.Restaurant {mustBeScalarOrEmpty(restaurant)} = WheelOfFortuneCookies.Restaurant.Restaurant.empty; % Restaurant object associated with the control.
    end
    
    methods (Access = private)
        function nVotesTempChanged(comp)
            arguments (Input)
                comp (1,1) WheelOfFortuneCookies.RestaurantVoteComponent
            end

            switch sign(comp.restaurant.nVotesTemp)
                case 1
                    textColor = [0,0.5,0];
                    signCharacter = "+";
                    backgroundColor = [0.8,1,0.8];
                case 0
                    textColor = "black";
                    signCharacter = "";
                    backgroundColor = "white";
                case -1
                    textColor = "red";
                    signCharacter = "";
                    backgroundColor = [1,0.8,0.8];
            end

            comp.ValueEditField.Value = signCharacter + comp.restaurant.nVotesTemp;
            comp.ValueEditField.FontColor = textColor;
            comp.ValueEditField.BackgroundColor = backgroundColor;

            notify(comp,"voteChanged");
        end

        function formatNameLabel(comp)
            arguments (Input)
                comp (1,1) WheelOfFortuneCookies.RestaurantVoteComponent
            end

            comp.RestaurantNameLabel.Text = ...
                comp.restaurant.name + " [" + comp.restaurant.nVotes + "]";
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddButton, SubtractButton
        function AddSubtractButtonPushed(comp, event)
            if event.Source == comp.AddButton
                valueChange = 1;
            else
                valueChange = -1;
            end
            
            comp.restaurant.nVotesTemp = comp.restaurant.nVotesTemp + valueChange;
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            if ~isempty(comp.restaurant)
                comp.formatNameLabel;
                comp.elTempVotesChanged = ...
                    listener(comp.restaurant,"nVotesTemp","PostSet",@(~,~)comp.nVotesTempChanged);
                comp.elVotesChanged = ...
                    listener(comp.restaurant,"nVotes","PostSet",@(~,~)comp.formatNameLabel);
            else
                comp.RestaurantNameLabel.Text = "<No Restaurant>";
                comp.elTempVotesChanged = event.listener.empty;
                comp.elVotesChanged = event.listener.empty;
            end
            
            if ~comp.enable
                comp.AddButton.Enable = false;
                comp.SubtractButton.Enable = false;
                comp.RestaurantNameLabel.Enable = false;
                comp.ValueEditField.Enable = false;
            else
                comp.AddButton.Enable = comp.addButtonEnabled;
                comp.SubtractButton.Enable = comp.subtractButtonEnabled;
                comp.RestaurantNameLabel.Enable = comp.labelAndEditFieldEnabled;
                comp.ValueEditField.Enable = comp.labelAndEditFieldEnabled;
            end
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 177 87];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.MainGrid = uigridlayout(comp);
            comp.ControlsGrid = uigridlayout(comp.MainGrid);
            comp.SubtractButton = uibutton(comp.ControlsGrid, 'push');
            comp.SubtractButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @AddSubtractButtonPushed, true);
            comp.SubtractButton.Text = '-';
            comp.AddButton = uibutton(comp.ControlsGrid, 'push');
            comp.AddButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @AddSubtractButtonPushed, true);
            comp.AddButton.Text = '+';
            comp.ValueEditField = uieditfield(comp.ControlsGrid, 'text');
            comp.ValueEditField.Editable = 'off';
            comp.ValueEditField.Value = '0';
            comp.RestaurantNameLabel = uilabel(comp.MainGrid);
            comp.RestaurantNameLabel.Text = 'Restaurant Name';
        end
    end
end