classdef WinnerMessageGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FigureWindow         matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        Image                matlab.ui.control.Image
        RestaurantNameLabel  matlab.ui.control.Label
        WinnerLabel          matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winnerRestaurant)
            arguments (Input)
                app (1,1) WheelOfFortuneCookies.WinnerMessageGUI
                winnerRestaurant (1,1) WheelOfFortuneCookies.Restaurant.Restaurant
            end
            
            app.RestaurantNameLabel.Text = winnerRestaurant.name;
            % Rename the window to contain the winner's name.
            app.FigureWindow.Name = winnerRestaurant.name + " is the Winner!";
            % If there is a logo image specified for the winner and that file can be found, display
            % it. Otherwise, resize the grid to essentially remove the image and then hide the image
            % so the "no image specified" default image isn't shown.
            if ~isempty(winnerRestaurant.logoImage)
                app.Image.ImageSource = winnerRestaurant.logoImage;
            else
                app.GridLayout.RowHeight = {'1x','1x','0.01x'};
                app.Image.Visible = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.FigureWindow = uifigure('Visible', 'off');
            app.FigureWindow.Name = '[Restaurant Name] is the Winner!';
            app.FigureWindow.Icon = fullfile(pathToMLAPP, 'Images', 'Logo.png');
            app.GridLayout = uigridlayout(app.FigureWindow);
            app.WinnerLabel = uilabel(app.GridLayout);
            app.WinnerLabel.Text = 'Winner:';
            app.RestaurantNameLabel = uilabel(app.GridLayout);
            app.RestaurantNameLabel.Text = '[Restaurant Name]';
            app.Image = uiimage(app.GridLayout);
            % Show the figure after all components are created
            app.FigureWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WinnerMessageGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FigureWindow)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FigureWindow)
        end
    end
end