classdef WheelOfFortuneCookiesGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FigureWindow                matlab.ui.Figure
        WindowGrid                  matlab.ui.container.GridLayout
        MainGrid                    matlab.ui.container.GridLayout
        GridLayout3                 matlab.ui.container.GridLayout
        VotingButtonsGrid           matlab.ui.container.GridLayout
        ResetAllVotesButton         matlab.ui.control.Button
        SurpriseMeButton            matlab.ui.control.Button
        ClearMyVotesButton          matlab.ui.control.Button
        VoteButton                  matlab.ui.control.Button
        RestaurantsGrid             matlab.ui.container.GridLayout
        GridLayout                  matlab.ui.container.GridLayout
        GridLayout2                 matlab.ui.container.GridLayout
        PointerPositionSlider       matlab.ui.control.Slider
        PointerPositionSliderLabel  matlab.ui.control.Label
        SpinButton                  matlab.ui.control.Button
        WheelAxes                   matlab.ui.control.UIAxes
        BottomControlsGrid          matlab.ui.container.GridLayout
        VersionHyperlink            matlab.ui.control.Hyperlink
        CloseButton                 matlab.ui.control.Button
        VersionPrefixLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        Restaurants (1,:) restaurant
        Wheel spinnerWheel {mustBeScalarOrEmpty}

        RestaurantControls (1,:)
    end

    properties (Dependent, Access = private)
        nRestaurants (1,1) double
        nVotesTotal (1,1) double
        nVotesTempTotal (1,1) double
    end

    properties (Constant, Access = private)
        nPtsCirc = 360
        nVotesPerPlayer = 4
    end

    methods
        function out = get.nRestaurants(app)
            out = numel(app.Restaurants);
        end

        function out = get.nVotesTotal(app)
            out = sum([app.Restaurants.nVotes]);
        end

        function out = get.nVotesTempTotal(app)
            out = sum(abs([app.Restaurants.nVotesTemp]));
        end
    end
    
    methods (Access = private)
        function addRestaurant(app,name)
            arguments
                app (1,1) WheelOfFortuneCookiesGUI
                name (1,:) string
            end

            for ii = 1:numel(name)
                app.Restaurants(app.nRestaurants+1) = restaurant(name=name(ii));
            end
        end

        function drawWheel(app,pointerPosition)
            arguments
                app
                pointerPosition = app.PointerPositionSlider.Value;
            end

            app.Wheel.draw([app.Restaurants.nVotes],[app.Restaurants.name],pointerPosition);
            
            
        end

        function addRestaurantControls(app)
             RG = app.RestaurantsGrid;
             RG.RowHeight = repmat({'fit'},[1,5]);
             RG.ColumnWidth = repmat({'1x'},[1,3]);

             for ii = 1:app.nRestaurants
                 rCell = uigridlayout(RG,[2,1],Padding=0);

                 rCell.RowHeight = {'fit','fit'};
    
                 controlsGrid = uigridlayout(rCell,[1,3],Padding=0);
                 controlsGrid.Layout.Row = 2;
    
                 L = uilabel(rCell,Text=app.Restaurants(ii).name,HorizontalAlignment='Center',FontSize=18);
                 L.Layout.Row = 1;
                 
                 RB = uibutton(controlsGrid,'push',Text="-",FontSize=24,ButtonPushedFcn=@(~,~)app.buttonClickedCallback(app.Restaurants(ii),-1));
                 EF = uieditfield(controlsGrid,'Text',Value="0",FontSize=18,HorizontalAlignment='center');
                 AB = uibutton(controlsGrid,'push',Text="+",FontSize=24,ButtonPushedFcn=@(~,~)app.buttonClickedCallback(app.Restaurants(ii),1));

                 UD.RemoveButton = RB;
                 UD.AddButton = AB;
                 UD.EditField = EF;
                 UD.Label = L;

                 app.RestaurantControls(end+1) = RB;
                 app.RestaurantControls(end+1) = AB;
                 app.RestaurantControls(end+1) = EF;
                 app.RestaurantControls(end+1) = L;

                 app.Restaurants(ii).UserData = UD;
             end
        end

        function buttonClickedCallback(app,R,pm)

            R.nVotesTemp = R.nVotesTemp + pm;

            switch sign(R.nVotesTemp)
                case 1
                    textColor = [0,0.5,0];
                    signChar = '+';
                    backgroundColor = [0.8,1,0.8];
                case 0
                    textColor = 'k';
                    signChar = '';
                    backgroundColor = 'w';
                case -1
                    textColor = 'r';
                    signChar = '';
                    backgroundColor = [1,0.8,0.8];
            end

            R.UserData.EditField.Value = sprintf('%s%i',signChar,R.nVotesTemp);
            R.UserData.EditField.FontColor = textColor;
            R.UserData.EditField.BackgroundColor = backgroundColor;


            app.updateVoteButtonsEnabledState;
        end

        function updateVoteButtonsEnabledState(app)
            app.VoteButton.Enable = app.nVotesTempTotal == app.nVotesPerPlayer;
            app.SurpriseMeButton.Enable = app.nVotesTempTotal < app.nVotesPerPlayer;
            app.ClearMyVotesButton.Enable = app.nVotesTempTotal > 0;
            app.ResetAllVotesButton.Enable = app.nVotesTotal > 0;

            app.PointerPositionSlider.Enable = app.nVotesTotal > 0;
            app.SpinButton.Enable = app.nVotesTotal > 0;

            for R = app.Restaurants
                if app.nVotesTempTotal < app.nVotesPerPlayer
                    R.UserData.AddButton.Enable = true;
                    R.UserData.RemoveButton.Enable = abs(R.nVotesTemp) < R.nVotes;
                    R.UserData.EditField.Enable = true;
                    R.UserData.Label.Enable = true;
                else
                    R.UserData.AddButton.Enable = R.nVotesTemp < 0;
                    R.UserData.RemoveButton.Enable = R.nVotesTemp > 0;
                    R.UserData.EditField.Enable = R.nVotesTemp ~= 0;
                    R.UserData.Label.Enable = R.nVotesTemp ~= 0;
                end
            end
        end
    end

    methods (Static, Access = private)
        function updateVoteCounts(e,EF)
            R = e.AffectedObject;

            EF.Value = num2str(R.nVotes);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.addRestaurant(["Afro Deli";
                "Blaze Pizza";
                "Burger King";
                "Cane's";
                "Chipotle";
                "D.P. Dough";
                "Five Guys";
                "Jimmy Johns";
                "McDonald's";
                "Mesa Pizza";
                "My Burger";
                "Naf Naf Grill";
                "Noodles & Co.";
                "Potbelly";
                "Punch Pizza";
                "QDOBA";
                "Subway";
                "Wally's"]);

            app.addRestaurantControls;
            app.updateVoteButtonsEnabledState;

            app.Wheel = spinnerWheel(app.WheelAxes);
            app.drawWheel;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: SpinButton
        function SpinButtonPushed(app, event)
            

            if app.VoteButton.Enable == true
                app.VoteButtonPushed;
            end

            set(app.RestaurantControls,'Enable',false);

            app.ResetAllVotesButton.Enable = false;
            app.ClearMyVotesButton.Enable = false;
            app.SurpriseMeButton.Enable = false;
            app.VoteButton.Enable = false;
            
            app.SpinButton.Enable = false;
            app.PointerPositionSlider.Enable = false;

            winnerName = app.Wheel.spin;

            app.PointerPositionSlider.Enable = true;
            app.SpinButton.Enable = true;

            app.updateVoteButtonsEnabledState;

            %{
            • Show restarant logo for the winner instead of icon
            %}
            if isstring(winnerName)
                uialert(app.FigureWindow,sprintf('%s is the winner!',winnerName),'Winner',Icon='success');
            else
                uialert(app.FigureWindow,sprintf(['Wow, you sure are lucky! You somehow managed to ',...
                    'land exactly between two restaurants.\n\nSend a picture of this message to Nate ',...
                    'to impress him, then spin again to find a winner.']),'Tie',Icon='error');
            end
        end

        % Button pushed function: VoteButton
        function VoteButtonPushed(app, event)
            hasTempVotes = abs([app.Restaurants.nVotesTemp])>0;
            
            for R = app.Restaurants(hasTempVotes)
                R.countTempVotes;
                app.buttonClickedCallback(R,0);
            end
            app.drawWheel;
        end

        % Button pushed function: ResetAllVotesButton
        function ResetAllVotesButtonPushed(app, event)
            % Prompt the user.
            dlgAns = uiconfirm(app.FigureWindow,...
                'Are you sure you''d like to reset all of the votes?',...
                'Confirm Reset','Options',{'Yes','No'},'DefaultOption','No');
            if strcmp(dlgAns,'No')
                return
            end
            
            hasTempVotes = abs([app.Restaurants.nVotesTemp])>0;
            hasVotes = [app.Restaurants.nVotes]>0;

            for R = app.Restaurants(hasVotes | hasTempVotes)
                R.resetVotes;
                app.buttonClickedCallback(R,0);
            end
            app.drawWheel;
        end

        % Button pushed function: ClearMyVotesButton
        function ClearMyVotesButtonPushed(app, event)
            hasTempVotes = abs([app.Restaurants.nVotesTemp])>0;

            for R = app.Restaurants(hasTempVotes)
                R.resetVotes(tempVotesOnly=true);
                app.buttonClickedCallback(R,0);
            end
        end

        % Button pushed function: SurpriseMeButton
        function SurpriseMeButtonPushed(app, event)
            alreadySelectedInds = find(abs([app.Restaurants.nVotesTemp])>0);
            
            while app.nVotesTempTotal < app.nVotesPerPlayer
                rInd = randi(app.nRestaurants);

                if any(rInd == alreadySelectedInds)
                    continue
                end

                
                R = app.Restaurants(rInd);
                addVal = rand > 0.5;
                if -R.nVotesTemp < R.nVotes && ~addVal
                    app.buttonClickedCallback(R,-1);
                else
                    app.buttonClickedCallback(R,1);
                end
            end

        end

        % Value changing function: PointerPositionSlider
        function PointerPositionSliderValueChanging(app, event)
            app.Wheel.drawPointer(event.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FigureWindow = uifigure('Visible', 'off');
            app.FigureWindow.Name = 'Wheel of Fortune Cookies';
            app.WindowGrid = uigridlayout(app.FigureWindow);
            app.BottomControlsGrid = uigridlayout(app.WindowGrid);
            app.VersionPrefixLabel = uilabel(app.BottomControlsGrid);
            app.VersionPrefixLabel.Text = 'Version:';
            app.CloseButton = uibutton(app.BottomControlsGrid, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.VersionHyperlink = uihyperlink(app.BottomControlsGrid);
            app.VersionHyperlink.Tooltip = {'Click here to go to the GitHub repository with the newest version.'};
            app.VersionHyperlink.URL = 'https://github.com/ThatMechEGuy/WheelOfFortuneCookies';
            app.VersionHyperlink.Text = '2022.12';
            app.MainGrid = uigridlayout(app.WindowGrid);
            app.GridLayout = uigridlayout(app.MainGrid);
            app.WheelAxes = uiaxes(app.GridLayout);
            title(app.WheelAxes, 'Title')
            xlabel(app.WheelAxes, 'X')
            ylabel(app.WheelAxes, 'Y')
            zlabel(app.WheelAxes, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.SpinButton = uibutton(app.GridLayout2, 'push');
            app.SpinButton.ButtonPushedFcn = createCallbackFcn(app, @SpinButtonPushed, true);
            app.SpinButton.Text = 'Spin';
            app.PointerPositionSliderLabel = uilabel(app.GridLayout2);
            app.PointerPositionSliderLabel.Text = 'Pointer Position';
            app.PointerPositionSlider = uislider(app.GridLayout2);
            app.PointerPositionSlider.Limits = [0 360];
            app.PointerPositionSlider.MajorTicks = [];
            app.PointerPositionSlider.MajorTickLabels = {''};
            app.PointerPositionSlider.ValueChangingFcn = createCallbackFcn(app, @PointerPositionSliderValueChanging, true);
            app.PointerPositionSlider.MinorTicks = [];
            app.PointerPositionSlider.Value = 180;
            app.GridLayout3 = uigridlayout(app.MainGrid);
            app.RestaurantsGrid = uigridlayout(app.GridLayout3);
            app.VotingButtonsGrid = uigridlayout(app.GridLayout3);
            app.VoteButton = uibutton(app.VotingButtonsGrid, 'push');
            app.VoteButton.ButtonPushedFcn = createCallbackFcn(app, @VoteButtonPushed, true);
            app.VoteButton.Text = 'Vote';
            app.ClearMyVotesButton = uibutton(app.VotingButtonsGrid, 'push');
            app.ClearMyVotesButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMyVotesButtonPushed, true);
            app.ClearMyVotesButton.Text = 'Clear My Votes';
            app.SurpriseMeButton = uibutton(app.VotingButtonsGrid, 'push');
            app.SurpriseMeButton.ButtonPushedFcn = createCallbackFcn(app, @SurpriseMeButtonPushed, true);
            app.SurpriseMeButton.Text = 'Surprise Me';
            app.ResetAllVotesButton = uibutton(app.VotingButtonsGrid, 'push');
            app.ResetAllVotesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAllVotesButtonPushed, true);
            app.ResetAllVotesButton.Text = 'Reset All Votes';
            % Show the figure after all components are created
            app.FigureWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WheelOfFortuneCookiesGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FigureWindow)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FigureWindow)
        end
    end
end